classdef harem_mohammed_smail < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        PlotAxes                 matlab.ui.control.UIAxes
        PlotIngLimitsPanel       matlab.ui.container.Panel
        YEditField_2Label        matlab.ui.control.Label
        YminInput                matlab.ui.control.NumericEditField
        xEditFieldLabel          matlab.ui.control.Label
        XmaxInput                matlab.ui.control.NumericEditField
        YEditFieldLabel          matlab.ui.control.Label
        YmaxInput                matlab.ui.control.NumericEditField
        XEditFieldLabel          matlab.ui.control.Label
        XminInput                matlab.ui.control.NumericEditField
        FunctionDetailsPanel     matlab.ui.container.Panel
        StartButton              matlab.ui.control.Button
        fxEditFieldLabel         matlab.ui.control.Label
        FunctionInput            matlab.ui.control.EditField
        aEditFieldLabel          matlab.ui.control.Label
        StartAInput              matlab.ui.control.NumericEditField
        bEditFieldLabel          matlab.ui.control.Label
        StartBInput              matlab.ui.control.NumericEditField
        cEditFieldLabel          matlab.ui.control.Label
        cEditField               matlab.ui.control.NumericEditField
        toleranceEditFieldLabel  matlab.ui.control.Label
        ToleranceInput           matlab.ui.control.NumericEditField
        iterationLabel           matlab.ui.control.Label
        IterationsInput          matlab.ui.control.NumericEditField
        ShowGridCheckbox         matlab.ui.control.CheckBox
        IntervalEditFieldLabel   matlab.ui.control.Label
        PauseTimeInput           matlab.ui.control.NumericEditField
        ResultsTable             matlab.ui.control.Table
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
 % Read inputs
            cla(app.PlotAxes);
            func_str = app.FunctionInput.Value;
            xmin = app.XminInput.Value;
            xmax = app.XmaxInput.Value;
            ymin = app.YminInput.Value;
            ymax = app.YmaxInput.Value;


            iterations = app.IterationsInput.Value;
            tolerance = app.ToleranceInput.Value;
            a = app.StartAInput.Value;
            b = app.StartBInput.Value;

            % Create function handle from input string
            f = str2func(['@(x) ' func_str]);

            % Plot the function
            x = linspace(xmin, xmax, 100);
            y = arrayfun(f, x);
            plot(app.PlotAxes, x, y);
            hold(app.PlotAxes, 'on');
            ylim(app.PlotAxes, [ymin, ymax]);
            xlim(app.PlotAxes, [xmin, xmax]);


            % Bisection algorithm with configurable pause time
            pause_time = app.PauseTimeInput.Value; % Get the pause time

            for i = 1:iterations
                c = (a + b) / 2; % Midpoint
                app.cEditField.Value=c;
                fa = f(a);
                fb = f(b);
                fc = f(c);

                % Add data to the table
                new_row = {i, a, b, c, fa, fb, fc};
                app.ResultsTable.Data = [app.ResultsTable.Data; new_row];
                
                % Update the plot with current points
                scatter(app.PlotAxes, [a, b, c], [fa, fb, fc], 'filled');
                plot(app.PlotAxes, [a, a], [0, fa], '--'); 
                plot(app.PlotAxes, [b, b], [0, fb], '--');
                plot(app.PlotAxes, [c, c], [0, fc], '--'); 
                % Bisection logic
                if abs(fc) < tolerance
                    break;
                elseif fc * fa < 0
                    b = c;
                else
                    a = c;
                end

                % Pause between iterations
                pause(pause_time); % Configurable pause time
            end

            hold(app.PlotAxes, 'off'); % Release the hold on the plot
       
        end

        % Value changed function: ShowGridCheckbox
        function onShowGridCheckboxChanged(app, event)
           if app.ShowGridCheckbox.Value
                grid(app.PlotAxes, 'on');
                app.PlotAxes.GridColor = [1, 0, 0]; 
                app.PlotAxes.XMinorGrid = 'on';
                app.PlotAxes.YMinorGrid = 'on';
            else
                grid(app.PlotAxes, 'off'); 
                app.PlotAxes.XMinorGrid = 'off';
                app.PlotAxes.YMinorGrid = 'off';
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PlotAxes = uiaxes(app.UIFigure);
            title(app.PlotAxes, 'The Graph')
            xlabel(app.PlotAxes, 'X')
            ylabel(app.PlotAxes, 'Y')
            app.PlotAxes.XAxisLocation = 'origin';
            app.PlotAxes.YAxisLocation = 'origin';
            app.PlotIngLimitsPanel = uipanel(app.UIFigure);
            app.PlotIngLimitsPanel.Title = '         PlotIng Limits';
            app.YEditField_2Label = uilabel(app.PlotIngLimitsPanel);
            app.YEditField_2Label.Text = '-Y';
            app.YminInput = uieditfield(app.PlotIngLimitsPanel, 'numeric');
            app.YminInput.Value = -40;
            app.xEditFieldLabel = uilabel(app.PlotIngLimitsPanel);
            app.xEditFieldLabel.Text = '+x';
            app.XmaxInput = uieditfield(app.PlotIngLimitsPanel, 'numeric');
            app.XmaxInput.Value = 40;
            app.YEditFieldLabel = uilabel(app.PlotIngLimitsPanel);
            app.YEditFieldLabel.Text = '+Y';
            app.YmaxInput = uieditfield(app.PlotIngLimitsPanel, 'numeric');
            app.YmaxInput.Value = 40;
            app.XEditFieldLabel = uilabel(app.PlotIngLimitsPanel);
            app.XEditFieldLabel.Text = '-X';
            app.XminInput = uieditfield(app.PlotIngLimitsPanel, 'numeric');
            app.XminInput.Value = -40;
            app.FunctionDetailsPanel = uipanel(app.UIFigure);
            app.FunctionDetailsPanel.Title = 'Function Details';
            app.StartButton = uibutton(app.FunctionDetailsPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Bisection';
            app.fxEditFieldLabel = uilabel(app.FunctionDetailsPanel);
            app.fxEditFieldLabel.Text = 'f(x)';
            app.FunctionInput = uieditfield(app.FunctionDetailsPanel, 'text');
            app.aEditFieldLabel = uilabel(app.FunctionDetailsPanel);
            app.aEditFieldLabel.Text = 'a';
            app.StartAInput = uieditfield(app.FunctionDetailsPanel, 'numeric');
            app.StartAInput.Value = -30;
            app.bEditFieldLabel = uilabel(app.FunctionDetailsPanel);
            app.bEditFieldLabel.Text = 'b';
            app.StartBInput = uieditfield(app.FunctionDetailsPanel, 'numeric');
            app.StartBInput.Value = 30;
            app.cEditFieldLabel = uilabel(app.FunctionDetailsPanel);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.FunctionDetailsPanel, 'numeric');
            app.toleranceEditFieldLabel = uilabel(app.FunctionDetailsPanel);
            app.toleranceEditFieldLabel.Text = 'tolerance';
            app.ToleranceInput = uieditfield(app.FunctionDetailsPanel, 'numeric');
            app.ToleranceInput.Value = 0.001;
            app.iterationLabel = uilabel(app.FunctionDetailsPanel);
            app.iterationLabel.Text = 'iteration';
            app.IterationsInput = uieditfield(app.FunctionDetailsPanel, 'numeric');
            app.IterationsInput.Value = 8;
            app.ShowGridCheckbox = uicheckbox(app.FunctionDetailsPanel);
            app.ShowGridCheckbox.ValueChangedFcn = createCallbackFcn(app, @onShowGridCheckboxChanged, true);
            app.ShowGridCheckbox.Text = 'Grid';
            app.IntervalEditFieldLabel = uilabel(app.FunctionDetailsPanel);
            app.IntervalEditFieldLabel.Text = 'Interval';
            app.PauseTimeInput = uieditfield(app.FunctionDetailsPanel, 'numeric');
            app.PauseTimeInput.Value = 1;
            app.ResultsTable = uitable(app.UIFigure);
            app.ResultsTable.ColumnName = {'Iteration'; 'a'; 'b'; 'c'; 'f(a)'; 'f(b)'; 'f(c)'};
            app.ResultsTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = harem_mohammed_smail

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end